classdef AMB_FSL_3_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AMB_FSLUIFigure              matlab.ui.Figure
        Panel_12                     matlab.ui.container.Panel
        Panel_19                     matlab.ui.container.Panel
        Panel_21                     matlab.ui.container.Panel
        Chn_load_file_list_2         matlab.ui.control.ListBox
        Panel_20                     matlab.ui.container.Panel
        pushbutton_delete_filename   matlab.ui.control.Button
        Chn_load_file_list           matlab.ui.control.ListBox
        Panel_14                     matlab.ui.container.Panel
        Panel_17                     matlab.ui.container.Panel
        pushbutton_suspected_result  matlab.ui.control.Button
        CheckBox_suspected_result    matlab.ui.control.CheckBox
        Chn_suspected_result         matlab.ui.control.ListBox
        Panel_16                     matlab.ui.container.Panel
        pushbutton_serious_result    matlab.ui.control.Button
        CheckBox_serious_result      matlab.ui.control.CheckBox
        Chn_serious_result           matlab.ui.control.ListBox
        Panel_15                     matlab.ui.container.Panel
        text_Scan_Progress           matlab.ui.control.Label
        pushbutton_total_result      matlab.ui.control.Button
        CheckBox_total_result        matlab.ui.control.CheckBox
        Chn_total_result             matlab.ui.control.ListBox
        Chn_num_normal_serious_suspected  matlab.ui.control.ListBox
        Panel_2                      matlab.ui.container.Panel
        Panel_11                     matlab.ui.container.Panel
        pushbutton_load_file         matlab.ui.control.Button
        pushbutton_load_dir          matlab.ui.control.Button
        Panel_10                     matlab.ui.container.Panel
        ButtonGroup_scn_mode         matlab.ui.container.ButtonGroup
        Button_scn_mode_3            matlab.ui.control.RadioButton
        Button_scn_mode_2            matlab.ui.control.RadioButton
        Button_scn_mode_1            matlab.ui.control.RadioButton
        Lamp_Start_Scan              matlab.ui.control.Lamp
        Switch_Start_Scan            matlab.ui.control.Switch
        Panel_9                      matlab.ui.container.Panel
        pushbutton_load_dir_2        matlab.ui.control.Button
        ButtonGroup_save_set         matlab.ui.container.ButtonGroup
        Button__save_set_2           matlab.ui.control.RadioButton
        Button__save_set_1           matlab.ui.control.RadioButton
        text3_5                      matlab.ui.control.Label
        save_resul_path              matlab.ui.control.EditField
        Panel_8                      matlab.ui.container.Panel
        pushbutton_Setting_3         matlab.ui.control.Button
        pushbutton_Setting_2         matlab.ui.control.Button
        pushbutton_Setting           matlab.ui.control.Button
        text_Setting                 matlab.ui.control.Label
    end


%%%%%%%%%%%%%添加自己需要用到的属性变量（用于全局传递）%%%%%%%%%%%%%%
        
    properties (Access = public)
        CApp % Description

        select_fileName; %备选着的具体文件名
        select_filepath; % 被选择的具体文件夹路径
        
    end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%添加自己用到的函数%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
    methods (Access = public)

        function ReadCsvData(app,filepath,filename)

        end

        %设置setting界面数目
        function SetData(app)
            %用.\config\SetData.csv中的数据进行初始化赋值
            csvFilePath = fullfile('.\config\', 'SetData_temp.csv');
            
            % 读取CSV文件
            csvData = importdata(csvFilePath);
            
            % 获取对应列的索引
            scn_channel_idx = find(strcmp(csvData.textdata, '﻿scn_channel'));
            scn_channel_max_idx = find(strcmp(csvData.textdata, 'scn_channel_max'));
            scn_suspected_threshold_idx = find(strcmp(csvData.textdata, 'scn_suspected_threshold'));
            scn_serious_threshold_idx = find(strcmp(csvData.textdata, 'scn_serious_threshold'));
            set_scanning_sensitivity_idx = find(strcmp(csvData.textdata, 'set_scanning_sensitivity'));
            set_scanning_density_idx = find(strcmp(csvData.textdata, 'set_scanning_density'));       

            
            % 获取对应列的数据（排除 NaN）
            scn_channel_data = csvData.data(scn_channel_idx, :);
            scn_channel_max_data = csvData.data(scn_channel_max_idx, :);
            scn_suspected_threshold_data = csvData.data(scn_suspected_threshold_idx, :);
            scn_serious_threshold_data = csvData.data(scn_serious_threshold_idx, :);
            set_scanning_sensitivity_data = csvData.data(set_scanning_sensitivity_idx, :);
            set_scanning_density_data = csvData.data(set_scanning_density_idx, :);
            
            scn_channel_data = scn_channel_data(~isnan(scn_channel_data));
            scn_channel_max_data = scn_channel_max_data(~isnan(scn_channel_max_data));
            scn_suspected_threshold_data = scn_suspected_threshold_data(~isnan(scn_suspected_threshold_data));
            scn_serious_threshold_data = scn_serious_threshold_data(~isnan(scn_serious_threshold_data));
            set_scanning_sensitivity_data = set_scanning_sensitivity_data(~isnan(set_scanning_sensitivity_data));
            set_scanning_density_data = set_scanning_density_data(~isnan(set_scanning_density_data));            
            
            % 将数值数据转换为字符串
            scn_channel_str = sprintf('%g,', scn_channel_data);
            scn_channel_max_str = sprintf('%g,', scn_channel_max_data);
            scn_suspected_threshold_str = sprintf('%g,', scn_suspected_threshold_data);
            scn_serious_threshold_str = sprintf('%g,', scn_serious_threshold_data);
            set_scanning_sensitivity_str = sprintf('%g,', set_scanning_sensitivity_data);
            set_scanning_density_str = sprintf('%g,', set_scanning_density_data);

            scn_channel = scn_channel_str(1:end-1);  % 移除末尾的逗号
            scn_channel_max = scn_channel_max_str(1:end-1);  % 移除末尾的逗号
            scn_suspected_threshold = scn_suspected_threshold_str(1:end-1);  % 移除末尾的逗号
            scn_serious_threshold = scn_serious_threshold_str(1:end-1);  % 移除末尾的逗号
            set_scanning_sensitivity = set_scanning_sensitivity_str(1:end-1);  % 移除末尾的逗号
            set_scanning_density = set_scanning_density_str(1:end-1);  % 移除末尾的逗号


%             % 待检通道
%             scn_channel = '2,3,5,6,4';
% 
%             % 保护间隙
%             scn_channel_max = '5,5,5,5,5';
% 
%             % 故障下界
%             scn_suspected_threshold = '0.25,0.25,0.25,0.25,0.25';
% 
%             % 故障上界
%             scn_serious_threshold = '0.85,0.85,0.85,0.85,0.85';
% 
%             % 敏感阈度
%             set_scanning_sensitivity = '0.05,0.005';
% 
%             % 扫描密度
%             set_scanning_density = '100';

                    
            SetDataInfo = struct('scn_channel',scn_channel,'scn_channel_max',scn_channel_max,...
                'scn_suspected_threshold',scn_suspected_threshold,'scn_serious_threshold',scn_serious_threshold,...
                'set_scanning_sensitivity',set_scanning_sensitivity,...                
                'set_scanning_density',set_scanning_density);
            
            app.pushbutton_Setting.UserData = SetDataInfo;
         
        end
        
        % 用于更新显示框
        function update_scan_result_display(app,clear_1,num_normal_serious_suspected,total_result,serious_result,suspected_result)
        
            if clear_1 ~= 1 % 清空
                    % 扫描进度
                app.text_Scan_Progress.Text = '扫描进度：';
                    % 数值部分
                app.Chn_num_normal_serious_suspected.Items = {'正常文件：','疑似故障：','严重故障：'};
                app.Chn_num_normal_serious_suspected.Value = {};
                    % 列表部分
                app.Chn_total_result.Items = {};
                app.Chn_total_result.Value = {};   
    
                % 更新"严重故障-文件列表"
                app.Chn_serious_result.Items = {};
                app.Chn_serious_result.Value = {};   
    
                % 更新"疑似故障-文件列表"
                app.Chn_suspected_result.Items = {};
                app.Chn_suspected_result.Value = {};             
                            
            else
                % 更新"整体扫描结果"
                    % 数值部分
                if ~isempty(num_normal_serious_suspected{1}) % 如果装了内容
                    app.Chn_num_normal_serious_suspected.Items = num_normal_serious_suspected;
                    app.Chn_num_normal_serious_suspected.Value = {};
                end
                    % 列表部分
                if ~isempty(total_result{1}) % 如果装了内容
                    app.Chn_total_result.Items = total_result;
                    app.Chn_total_result.Value = {}; 
                end
    
                % 更新"严重故障-文件列表"
                if ~isempty(serious_result{1})
                    app.Chn_serious_result.Items = serious_result;
                    app.Chn_serious_result.Value = {};
                end
    
                % 更新"疑似故障-文件列表"
                if ~isempty(suspected_result{1})
                    app.Chn_suspected_result.Items = suspected_result;
                    app.Chn_suspected_result.Value = {};
                end
                
            end

        end

        %用于2.0的参数设置功能
        function parameter_status = set_parameter_check(app) % 自动检查参数的设置是否合理（以待检通道的参数为基准）：返回为1是正常的，非1为异常
            % 状态代码
            parameter_status = '1'; % 1：参数输入正确
            % 代码说明(带上分行符)
            parameter_status_Interpretation = ['1：参数输入正确',newline,...
                                               '2：输入参数格式错误',newline,...
                                               '3：“保护间隙”个数与“待检通道”个数不符合',newline,...
                                               '4：“故障下界”个数与“待检通道”个数不符合',newline,...
                                               '5：“故障上界”个数与“待检通道”个数不符合',newline,...
                                               '6：“敏感阈度”个数不为2',newline,...
                                               '7：“扫描密度”个数不为1',newline];
            try
                scn_channel = strsplit(app.scn_channel.Value,',');
                scn_channel = str2double(scn_channel) % 如果原始字符串包含非数字字符或空格，str2double 会在那些位置生成 NaN（Not a Number）
                scn_channel_max = strsplit(app.scn_channel_max.Value,',');
                scn_channel_max = str2double(scn_channel_max)
                scn_suspected_threshold = strsplit(app.scn_suspected_threshold.Value,',');
                scn_suspected_threshold = str2double(scn_suspected_threshold)
                scn_serious_threshold = strsplit(app.scn_serious_threshold.Value,',');
                scn_serious_threshold = str2double(scn_serious_threshold)
                set_scanning_sensitivity = strsplit(app.set_scanning_sensitivity.Value,',');
                set_scanning_sensitivity = str2double(set_scanning_sensitivity)
                set_scanning_density = strsplit(app.set_scanning_density.Value,',');
                set_scanning_density = str2double(set_scanning_density)
            
                % 检测是否有非数字字符
                if any(isnan(scn_channel)) || any(isnan(scn_channel_max)) || any(isnan(scn_suspected_threshold)) || any(isnan(scn_serious_threshold)) || any(isnan(set_scanning_sensitivity))
                    parameter_status = [parameter_status,'/2'];
                else
                    if length(scn_channel) ~= length(scn_channel_max)
                        parameter_status = [parameter_status,'/3'];
                    end
                    if length(scn_channel) ~= length(scn_suspected_threshold)
                        parameter_status = [parameter_status,'/4'];
                    end
                    if length(scn_channel) ~= length(scn_serious_threshold)
                        parameter_status = [parameter_status,'/5'];
                    end
                    if length(set_scanning_sensitivity) ~= 2
                        parameter_status = [parameter_status,'/6'];
                    end
                    if length(set_scanning_density) ~= 1
                        parameter_status = [parameter_status,'/7'];
                    end      
            
                end
            
                if length(parameter_status) > 1
                    % 在msgbox弹窗中显示错误信息以及错误代码和错误代码说明
                    msgbox(['参数输入错误:',newline,parameter_status_Interpretation,newline,'错误代码：',parameter_status(3:end)],'错误','error');   
                else
                    parameter_status = str2double(parameter_status)
                end
            
            catch
                % 在msgbox弹窗中显示错误信息以及错误代码和错误代码说明
                msgbox('参数输入错误，请检查输入：','错误','error');   
            end
        end

        % 用于扫描结果的保存功能
        function save_file_scan_result(app, save_file_name, File_scan_results)
            try
                % 先检测要保存的内容是否为空
                if isempty(File_scan_results)
                    return
                end
                % 获取当前时间戳
                time_stamp = datestr(now, 'yyyymmddHHMMSS');
                % 生成文件名
                file_name = [save_file_name, time_stamp, '.txt'];
                % 生成文件路径
                if app.save_resul_path.Value(end) == '\'
                    file_path = fullfile(app.save_resul_path.Value, file_name);
                else
                    file_path = fullfile(app.save_resul_path.Value, '\', file_name);
                end
        
                % 保存文件内容
                fid = fopen(file_path, 'w');
                for i = 1:length(File_scan_results)
                    fprintf(fid, '%s\n', File_scan_results{i});
                end
                fclose(fid);
        
                msgbox('扫描结果保存成功');
            catch
                msgbox('扫描结果保存失败');
            end
            
        end
    end

    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            SetData(app)

        end

        % Button pushed function: pushbutton_load_file
        function pushbutton_load_fileButtonPushed(app, event)
            app.pushbutton_load_file.BackgroundColor = [0 1 0];

            % 这是文件选择框打开的位置
            if isempty(app.select_filepath)==1
                uigetfile_path_temp = 'C:\';
            else
                uigetfile_path_temp = app.select_filepath;
            end

            % 读取批次文件
            [fileName,filePath] = uigetfile({'*.txt;*.tdms'},'选择文件',uigetfile_path_temp,'MultiSelect','on');

%             if strcmp(class(fileName), 'double')||strcmp(class(filePath), 'double')
            if isa(fileName, 'double')||isa(filePath, 'double')
                msgbox('文件载入有误！','错误','error')
                app.pushbutton_load_file.BackgroundColor = [1 1 1];
                return
            else
                % uigetfile 函数在选择一个文件时返回一个字符串，而在选择多个文件时返回一个单元格数组,为避免后续比对时索引出错，这里要先判断是单个还是多个文件
                % 要用isa 之间判断，因为如果用class，它返回的是 'cell' 字符串，而不是 cell 类型。因此，需要使用字符串进行比较麻烦
                if isa(fileName, 'char')
                    fileName = cellstr(fileName);%将字符串数组（在matlab中字符串是单个字符构成的 数组 ）也变为 1个 的cell
                end

                for i = 1:length(fileName)
                    if (strcmp(fileName{1}(end-2:end),'txt') && strcmp(fileName{i}(end-2:end),'dms')) || ((strcmp(fileName{i}(end-2:end),'txt') && strcmp(fileName{1}(end-2:end),'dms')))

                        quest_temp = questdlg('载入选取文件中的.txt还是.tdms类型文件？','载入文件类型选择','.txt','.tdms','.txt');
                        if isequal(quest_temp,'.txt')
                            % 只保留.txt文件，去掉fileName_temp中没有被赋值的元素
                            fileName_temp = fileName;
                            for i = 1:length(fileName)
                                if strcmp(fileName{i}(end-2:end),'dms')
                                    fileName_temp{i} = [];
                                end
                            end
                            fileName_temp = fileName_temp(~cellfun('isempty',fileName_temp));
                            fileName = fileName_temp;
                        else
                            % 只保留.tdms文件，去掉fileName_temp中没有被赋值的元素
                            fileName_temp = fileName;
                            for i = 1:length(fileName)
                                if strcmp(fileName{i}(end-2:end),'txt')
                                    fileName_temp{i} = [];
                                end
                            end
                            fileName_temp = fileName_temp(~cellfun('isempty',fileName_temp));
                            fileName = fileName_temp;
                        end
                        app.select_filepath = filePath; 
                    end
                end


                app.select_fileName = fileName;
                app.select_filepath = filePath; 

            end
            
            % 确认文件被成功定位后 
                % 关闭"载入文件夹"按钮
            app.pushbutton_load_dir.BackgroundColor = [1 1 1];
    
                % 关闭启动按钮的开关和指示灯，但是不要跟新右边的结果显示框（只有当文件被正确读取载入后才更新）
            app.Switch_Start_Scan.Value = '关闭扫描';
            app.Lamp_Start_Scan.Color = [1,1,1];

                % 更新 "载入文件"显示框
            app.Chn_load_file_list.Items = app.select_fileName;
            app.Chn_load_file_list.Value = app.select_fileName{1,1};

                % 清空"扫描结果"显示框，为这一批次的扫描做准备
            update_scan_result_display(app,2);


        end

        % Button pushed function: pushbutton_load_dir
        function pushbutton_load_dirButtonPushed(app, event)
            app.pushbutton_load_dir.BackgroundColor = [0 1 0];

            % 这是文件选择框打开的位置
            if isempty(app.select_filepath)==1
                uigetdir_path_temp = 'C:\';
            else
                uigetdir_path_temp = app.select_filepath;
            end
            
            % 读取批次文件

            [filePath] = uigetdir(uigetdir_path_temp, '选择文件夹')

            if filePath == 0
                msgbox('文件夹载入有误！','错误','error')
                app.pushbutton_load_dir.BackgroundColor = [1 1 1];
                return
            else               
                file_name_txt_temp = dir(fullfile(filePath,'*.txt'));
                file_name_tdms_temp = dir(fullfile(filePath,'*.tdms'));
                file_name_txt = {file_name_txt_temp(:).name}
                file_name_tdms = {file_name_tdms_temp(:).name} 

                if (isempty(file_name_txt)==1) & (isempty(file_name_tdms)==1)
                    msgbox('文件夹中无规定格式的数据文件！','错误','error')
                    app.pushbutton_load_dir.BackgroundColor = [1 1 1];
                    return

                elseif (isempty(file_name_txt)==0) & (isempty(file_name_tdms)==0)
                    quest_temp = questdlg('选择读取文件夹的.txt还是.tdms类型文件？','载入文件类型选择','.txt','.tdms','.txt');
                    if isequal(quest_temp,'.txt')
                        app.select_fileName = file_name_txt;
                    else
                        app.select_fileName = file_name_tdms
                    end
                    app.select_filepath = filePath; 

                elseif isempty(file_name_txt_temp)==0
                    app.select_filepath = filePath; 
                    app.select_fileName = file_name_txt;

                else
                    app.select_filepath = filePath; 
                    app.select_fileName = file_name_tdms;
                end
            end   
    
            % 只有当正确读入新文件时才执行下面的操作
                % 关闭"载入文件夹"按钮
            app.pushbutton_load_file.BackgroundColor = [1 1 1];

                % 关闭启动按钮的开关和指示灯
            app.Switch_Start_Scan.Value = '关闭扫描';
            app.Lamp_Start_Scan.Color = [1,1,1];

                % 更新 "载入文件"显示框
            app.Chn_load_file_list.Items = app.select_fileName;
            app.Chn_load_file_list.Value = app.select_fileName{1,1};
                
                % 清空"扫描结果"显示框，为这一批次的扫描做准备
            update_scan_result_display(app,2);


        end

        % Value changed function: Switch_Start_Scan
        function Switch_Start_ScanValueChanged(app, event)
            value = app.Switch_Start_Scan.Value;

            % 二次确认框
            quest_temp = questdlg('是否进行文件扫描','文件扫描','启动','取消','取消');            
            if isequal(quest_temp,'取消')
                app.Switch_Start_Scan.Value = '关闭扫描';
                return
            end

            % 更新UI显示标注为：启动扫描
            app.Switch_Start_Scan.Value = '启动扫描';
            app.Lamp_Start_Scan.Color = [1 0 0]; %启动、或者没完成时是红灯

            % 自检参数是否设置正确（只检测逻辑）
            parameter_status = set_parameter_check(app); % 自带错误弹窗
            if parameter_status ~= 1 % 如果参数设置错误              
                app.Switch_Start_Scan.Value = '关闭扫描';
                app.Lamp_Start_Scan.Color = [1 1 1]; 
                return
            end

            % 检查当前的待检测文件是否为空
            if isempty(app.select_fileName)==0 & isempty(app.select_filepath)==0
                
%-------------------------------------------------------------%
%---------------------------开始扫描---------------------------%
                num_normal_serious_suspected_num = cell(1, 3);
                num_normal_serious_suspected_num(:) = {0};   
                
                total_result_display = cell(1, 1); % 默认向列方向扩展

                serious_result_display = cell(1, 1);
                suspected_result_display = cell(1, 1);

                % 
                h = waitbar(round(1/length(app.select_fileName),4),'正在进行文件扫描','CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
                setappdata(h,'canceling',0);              


                % 开始逐个的进行文件结果的扫描 - 每扫描一次就及时的更新右边的扫描结果框最上方的用+*表示正在进行统计
                for i = 1:length(app.select_fileName)

                    %初始化
                    num_normal_serious_suspected = {'正常文件：','疑似故障：','严重故障：'}

                    % 进度条
                    str = ['文件扫描中...',num2str(i/length(app.select_fileName)*100,'%0.2f'),'%'];
                    waitbar(i/length(app.select_fileName),h,str);

                    % 扫描进度框显示
                    app.text_Scan_Progress.Text = strcat('扫描进度：', num2str(i/length(app.select_fileName)*100,'%0.2f'), '%')

                    % 在载入文件框，显示当前正在扫描的文件
                    app.Chn_load_file_list.Value = app.select_fileName{i};

                    
                    % 扫描过程中可临时取消，此时仅显示当前达到的扫描进度
                    if getappdata(h,'canceling') % 如果在中途取消了扫描                         
                        delete(h);                    
                        msgbox('全域扫描已取消！','注意：','warn');
                        app.Switch_Start_Scan.Value = '关闭扫描';
                        app.Lamp_Start_Scan.Color = [0 0 1]; %只扫描了部分文件就中途停止——蓝灯
                        return
                    end

        %-----------------------------------------------------------------%
        %---------------------------单个文件扫描---------------------------%                    
                    if app.select_fileName{i}(end-2:end) == 'txt'% 对单个txt文件 进行扫描

                        FileScanningSingle_temp = FileScanningSingleTXT(app.ButtonGroup_scn_mode.SelectedObject.Text, ...
                            app.scn_channel.Value, ...
                            app.scn_channel_max.Value, ...
                            app.scn_serious_threshold.Value, ...
                            app.scn_suspected_threshold.Value, ...
                            app.set_scanning_sensitivity.Value, ...
                            app.select_fileName{i}, ...
                            app.select_filepath)
  
                    elseif app.select_fileName{i}(end-2:end) == 'dms' % 对单个txt文件 进行扫描
                        FileScanningSingle_temp = FileScanningSingleTDMS(app.ButtonGroup_scn_mode.SelectedObject.Text, ...
                            app.scn_channel.Value, ...
                            app.scn_channel_max.Value, ...
                            app.scn_serious_threshold.Value, ...
                            app.scn_suspected_threshold.Value, ...
                            app.set_scanning_sensitivity.Value, ...
                            app.select_fileName{i}, ...
                            app.select_filepath)
                            
                    else
    
                    end

                    num_normal_serious_suspected_num{FileScanningSingle_temp.status_label} = 1 + num_normal_serious_suspected_num{FileScanningSingle_temp.status_label};
                    num_normal_serious_suspected_num_str = cellfun(@num2str, num_normal_serious_suspected_num, 'UniformOutput', false);
                    for str_idx = 1:length(num_normal_serious_suspected_num_str)
                        num_nor_ser_sus_num_str_display{str_idx} = [num_normal_serious_suspected{str_idx} num_normal_serious_suspected_num_str{str_idx}];
                    end
                    
                    if isempty(total_result_display{1}) % 如果还没装过内容
                        total_result_display = cellstr(FileScanningSingle_temp.filename_sus_ser_display);
                    else
                        total_result_display = [total_result_display, FileScanningSingle_temp.filename_sus_ser_display];
                    end
                    
                    if FileScanningSingle_temp.status_label == 3
                        if isempty(serious_result_display{1}) % 如果还没装过内容
                            serious_result_display = cellstr(FileScanningSingle_temp.filename_sus_ser_display);
                        else
                            serious_result_display = [serious_result_display, FileScanningSingle_temp.filename_sus_ser_display];
                        end
                        
                    elseif FileScanningSingle_temp.status_label == 2
                        if isempty(suspected_result_display{1}) % 如果还没装过内容
                            suspected_result_display = cellstr(FileScanningSingle_temp.filename_sus_ser_display);
                        else
                            suspected_result_display = [suspected_result_display, FileScanningSingle_temp.filename_sus_ser_display];
                        end
    
                    end

        %---------------------------单个文件扫描---------------------------% 
        %-----------------------------------------------------------------%
                    
                    % 更新右边的扫描结果框
                    update_scan_result_display(app,1,num_nor_ser_sus_num_str_display,total_result_display,serious_result_display,suspected_result_display);


                    if i == length(app.select_fileName) % 扫描完成
                        app.Switch_Start_Scan.Value = '关闭扫描';
                        app.Lamp_Start_Scan.Color = [0 1 0]; % 扫描成功——绿灯 
                    end
        
                end
                
                % 保存扫描结果
                if app.ButtonGroup_save_set.SelectedObject.Text == '自动保存'
                    save_file_scan_result(app,'File_scan_results_',app.Chn_total_result.Items)
                end

                delete(h);
                msgbox('全域扫描诊断完成');

%---------------------------开始扫描---------------------------%
%-------------------------------------------------------------% 
            else % 如果检查到当前的待检测文件为空，就不进行扫描操作
                msgbox('待检测数据文件错误！','错误','error')
                app.Switch_Start_Scan.Value = '关闭扫描';
                app.Lamp_Start_Scan.Color = [1 1 1];  
            
            end


        end

        % Button pushed function: pushbutton_delete_filename
        function pushbutton_delete_filenameButtonPushed(app, event)
            if isempty(app.Chn_load_file_list.Value)==0
                % 不用找了，因为不会在同一个文件夹下存在同名的多个文件夹
                % 从app.select_fileName中删除已经选择的文件名，并清除app.select_fileName中的空行
                for j = 1:length(app.Chn_load_file_list.Value)
                    for i = 1:length(app.select_fileName)
                        if strcmp(app.select_fileName{1,i},app.Chn_load_file_list.Value(j))==1
                            app.select_fileName(i) = [];
                            app.select_fileName = app.select_fileName(~cellfun('isempty',app.select_fileName));
                            break
                        end
                    end
                end
                
            
                % 如果删除后，还剩有文件名，就正常更新 "载入文件"显示框，否则没文件时.Value 值应该为空
                app.Chn_load_file_list.Items = app.select_fileName;
                if isempty(app.select_fileName)==0
                    app.Chn_load_file_list.Value = app.select_fileName{1,1};
                else
                    app.Chn_load_file_list.Value = {};
                end

                % 将扫描启动等关闭，准备好下一次的扫描
                app.Lamp_Start_Scan.Color = [1 1 1];
            
            end
        end

        % Key press function: AMB_FSLUIFigure
        function AMB_FSLUIFigureKeyPress(app, event)
            key = event.Key;
            switch key 
                case 'delete'
                    pushbutton_delete_filenameButtonPushed(app,event);
            end
        end

        % Button pushed function: pushbutton_total_result
        function pushbutton_total_resultButtonPushed(app, event)
            % 判断是全选还是手选导出
            if app.CheckBox_total_result.Value == 1
                save_file_scan_result(app,'total_result_',app.Chn_total_result.Items)                
            else
                save_file_scan_result(app,'total_result_',app.Chn_total_result.Value)
            end

        end

        % Button pushed function: pushbutton_serious_result
        function pushbutton_serious_resultButtonPushed(app, event)
            % 判断是全选还是手选导出
            if app.CheckBox_serious_result.Value == 1
                save_file_scan_result(app,'serious_result_',app.Chn_serious_result.Items)                
            else
                save_file_scan_result(app,'serious_result_',app.Chn_serious_result.Value)
            end

        end

        % Button pushed function: pushbutton_suspected_result
        function pushbutton_suspected_resultButtonPushed(app, event)
            % 判断是全选还是手选导出
            if app.CheckBox_suspected_result.Value == 1
                save_file_scan_result(app,'suspected_result_',app.Chn_suspected_result.Items)                
            else
                save_file_scan_result(app,'suspected_result_',app.Chn_suspected_result.Value)
            end

        end

        % Button pushed function: pushbutton_Setting
        function pushbutton_Setting_Callback(app, event)
            
            app.CApp = SetPar(app);  %调用子程序
                   
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AMB_FSLUIFigure = uifigure('Visible', 'off');
            app.AMB_FSLUIFigure.Name = 'AMB_FSL';
            app.AMB_FSLUIFigure.Icon = fullfile(pathToMLAPP, 'icon', 'AMB_FSL 软件封面.png');
            app.AMB_FSLUIFigure.KeyPressFcn = createCallbackFcn(app, @AMB_FSLUIFigureKeyPress, true);
            app.text_Setting = uilabel(app.AMB_FSLUIFigure);
            app.text_Setting.Tag = 'text_Setting';
            app.text_Setting.Text = {'AMB数据文件'; '扫描软件'};
            app.Panel_2 = uipanel(app.AMB_FSLUIFigure);
            app.Panel_2.Title = '文件扫描';
            app.Panel_8 = uipanel(app.Panel_2);
            app.Panel_8.Title = '参数设置';
            app.pushbutton_Setting = uibutton(app.Panel_8, 'push');
            app.pushbutton_Setting.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_Setting_Callback, true);
            app.pushbutton_Setting.Tag = 'pushbutton_Setting';
            app.pushbutton_Setting.Text = '手动设置';
            app.pushbutton_Setting_2 = uibutton(app.Panel_8, 'push');
            app.pushbutton_Setting_2.Tag = 'pushbutton_Setting';
            app.pushbutton_Setting_2.Text = '载入参数';
            app.pushbutton_Setting_3 = uibutton(app.Panel_8, 'push');
            app.pushbutton_Setting_3.Tag = 'pushbutton_Setting';
            app.pushbutton_Setting_3.Text = '参数导出';
            app.Panel_9 = uipanel(app.Panel_2);
            app.Panel_9.Title = '结果保存';
            app.save_resul_path = uieditfield(app.Panel_9, 'text');
            app.save_resul_path.Tag = 'edit_MaxLimit';
            app.save_resul_path.Value = '.\Save Results';
            app.text3_5 = uilabel(app.Panel_9);
            app.text3_5.Tag = 'text3';
            app.text3_5.Text = '导出路径';
            app.ButtonGroup_save_set = uibuttongroup(app.Panel_9);
            app.ButtonGroup_save_set.Title = '结果保存方式';
            app.Button__save_set_1 = uiradiobutton(app.ButtonGroup_save_set);
            app.Button__save_set_1.Text = '自动保存';
            app.Button__save_set_1.Value = true;
            app.Button__save_set_2 = uiradiobutton(app.ButtonGroup_save_set);
            app.Button__save_set_2.Text = '手动保存';
            app.pushbutton_load_dir_2 = uibutton(app.Panel_9, 'push');
            app.pushbutton_load_dir_2.Tag = 'pushbutton_ExportData';
            app.pushbutton_load_dir_2.Icon = fullfile(pathToMLAPP, 'icon', '载入文件夹.png');
            app.pushbutton_load_dir_2.Text = '导出路径选择';
            app.Panel_10 = uipanel(app.Panel_2);
            app.Panel_10.Title = '扫描启动';
            app.Switch_Start_Scan = uiswitch(app.Panel_10, 'slider');
            app.Switch_Start_Scan.Items = {'关闭扫描', '启动扫描'};
            app.Switch_Start_Scan.ValueChangedFcn = createCallbackFcn(app, @Switch_Start_ScanValueChanged, true);
            app.Switch_Start_Scan.Value = '关闭扫描';
            app.Lamp_Start_Scan = uilamp(app.Panel_10);
            app.ButtonGroup_scn_mode = uibuttongroup(app.Panel_10);
            app.ButtonGroup_scn_mode.Title = '模式选择';
            app.Button_scn_mode_1 = uiradiobutton(app.ButtonGroup_scn_mode);
            app.Button_scn_mode_1.Text = '快速扫描';
            app.Button_scn_mode_1.Value = true;
            app.Button_scn_mode_2 = uiradiobutton(app.ButtonGroup_scn_mode);
            app.Button_scn_mode_2.Text = '模式-2';
            app.Button_scn_mode_3 = uiradiobutton(app.ButtonGroup_scn_mode);
            app.Button_scn_mode_3.Text = '模式-3';
            app.Panel_11 = uipanel(app.Panel_2);
            app.Panel_11.Title = '文件载入';
            app.pushbutton_load_dir = uibutton(app.Panel_11, 'push');
            app.pushbutton_load_dir.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_load_dirButtonPushed, true);
            app.pushbutton_load_dir.Tag = 'pushbutton_ExportData';
            app.pushbutton_load_dir.Icon = fullfile(pathToMLAPP, 'icon', '载入文件夹.png');
            app.pushbutton_load_dir.Text = '载入文件夹';
            app.pushbutton_load_file = uibutton(app.Panel_11, 'push');
            app.pushbutton_load_file.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_load_fileButtonPushed, true);
            app.pushbutton_load_file.Tag = 'pushbutton_ExportData';
            app.pushbutton_load_file.Icon = fullfile(pathToMLAPP, 'icon', '载入文件.png');
            app.pushbutton_load_file.Text = '载入文件';
            app.Panel_12 = uipanel(app.AMB_FSLUIFigure);
            app.Panel_14 = uipanel(app.Panel_12);
            app.Panel_14.Title = '扫描结果显示框';
            app.Panel_15 = uipanel(app.Panel_14);
            app.Panel_15.Title = '整体结果';
            app.Chn_num_normal_serious_suspected = uilistbox(app.Panel_15);
            app.Chn_num_normal_serious_suspected.Items = {'正常文件：', '疑似故障：', '严重故障：'};
            app.Chn_num_normal_serious_suspected.Multiselect = 'on';
            app.Chn_num_normal_serious_suspected.Tag = 'ListBox_Chn';
            app.Chn_num_normal_serious_suspected.Value = {};
            app.Chn_total_result = uilistbox(app.Panel_15);
            app.Chn_total_result.Items = {};
            app.Chn_total_result.Multiselect = 'on';
            app.Chn_total_result.Tag = 'ListBox_Chn';
            app.Chn_total_result.Value = {};
            app.CheckBox_total_result = uicheckbox(app.Panel_15);
            app.CheckBox_total_result.Text = '导出-全选';
            app.CheckBox_total_result.Value = true;
            app.pushbutton_total_result = uibutton(app.Panel_15, 'push');
            app.pushbutton_total_result.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_total_resultButtonPushed, true);
            app.pushbutton_total_result.Tag = 'pushbutton_ExportData';
            app.pushbutton_total_result.Text = '导出';
            app.text_Scan_Progress = uilabel(app.Panel_15);
            app.text_Scan_Progress.Tag = 'text_Setting';
            app.text_Scan_Progress.Text = '扫描进度：';
            app.Panel_16 = uipanel(app.Panel_14);
            app.Panel_16.Title = '严重故障-文件列表';
            app.Chn_serious_result = uilistbox(app.Panel_16);
            app.Chn_serious_result.Items = {};
            app.Chn_serious_result.Multiselect = 'on';
            app.Chn_serious_result.Tag = 'ListBox_Chn';
            app.Chn_serious_result.Value = {};
            app.CheckBox_serious_result = uicheckbox(app.Panel_16);
            app.CheckBox_serious_result.Text = '导出-全选';
            app.CheckBox_serious_result.Value = true;
            app.pushbutton_serious_result = uibutton(app.Panel_16, 'push');
            app.pushbutton_serious_result.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_serious_resultButtonPushed, true);
            app.pushbutton_serious_result.Tag = 'pushbutton_ExportData';
            app.pushbutton_serious_result.Text = '导出';
            app.Panel_17 = uipanel(app.Panel_14);
            app.Panel_17.Title = '疑似故障-文件列表';
            app.Chn_suspected_result = uilistbox(app.Panel_17);
            app.Chn_suspected_result.Items = {};
            app.Chn_suspected_result.Multiselect = 'on';
            app.Chn_suspected_result.Tag = 'ListBox_Chn';
            app.Chn_suspected_result.Value = {};
            app.CheckBox_suspected_result = uicheckbox(app.Panel_17);
            app.CheckBox_suspected_result.Text = '导出-全选';
            app.CheckBox_suspected_result.Value = true;
            app.pushbutton_suspected_result = uibutton(app.Panel_17, 'push');
            app.pushbutton_suspected_result.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_suspected_resultButtonPushed, true);
            app.pushbutton_suspected_result.Tag = 'pushbutton_ExportData';
            app.pushbutton_suspected_result.Text = '导出';
            app.Panel_19 = uipanel(app.Panel_12);
            app.Panel_19.Title = '载入文件显示框';
            app.Panel_20 = uipanel(app.Panel_19);
            app.Panel_20.Title = '载入待扫描文件';
            app.Chn_load_file_list = uilistbox(app.Panel_20);
            app.Chn_load_file_list.Items = {};
            app.Chn_load_file_list.Multiselect = 'on';
            app.Chn_load_file_list.Tag = 'ListBox_Chn';
            app.Chn_load_file_list.Value = {};
            app.pushbutton_delete_filename = uibutton(app.Panel_20, 'push');
            app.pushbutton_delete_filename.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_delete_filenameButtonPushed, true);
            app.pushbutton_delete_filename.Tag = 'pushbutton_ExportData';
            app.pushbutton_delete_filename.Text = '删除';
            app.Panel_21 = uipanel(app.Panel_19);
            app.Panel_21.Title = '载入参数';
            app.Chn_load_file_list_2 = uilistbox(app.Panel_21);
            app.Chn_load_file_list_2.Items = {};
            app.Chn_load_file_list_2.Multiselect = 'on';
            app.Chn_load_file_list_2.Tag = 'ListBox_Chn';
            app.Chn_load_file_list_2.Value = {};
            % Show the figure after all components are created
            app.AMB_FSLUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AMB_FSL_3_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AMB_FSLUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AMB_FSLUIFigure)
        end
    end
end