classdef SetPar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Panel                     matlab.ui.container.Panel
        pushbutton_Return         matlab.ui.control.Button
        Panel_2                   matlab.ui.container.Panel
        text3_6                   matlab.ui.control.Label
        scn_serious_threshold     matlab.ui.control.EditField
        text3_5                   matlab.ui.control.Label
        scn_channel_max           matlab.ui.control.EditField
        text3_4                   matlab.ui.control.Label
        set_scanning_density      matlab.ui.control.EditField
        text3_3                   matlab.ui.control.Label
        set_scanning_sensitivity  matlab.ui.control.EditField
        text3_2                   matlab.ui.control.Label
        scn_suspected_threshold   matlab.ui.control.EditField
        scn_channel               matlab.ui.control.EditField
        text3                     matlab.ui.control.Label
    end

    properties (Access = private)
        FApp % Description
    end
    
    methods (Access = private)
        
        function SetDataInfo = SetData(app,SetDataInfo)
            %设置数据,读取模式，用于子程序初始化
            if nargin == 2
            % 待检通道
                app.scn_channel.Value = SetDataInfo.scn_channel;
    
                % 保护间隙
                app.scn_channel_max.Value = SetDataInfo.scn_channel_max;
    
                % 故障下界
                app.scn_suspected_threshold.Value = SetDataInfo.scn_suspected_threshold;
    
                % 故障上界
                app.scn_serious_threshold.Value = SetDataInfo.scn_serious_threshold;
    
                % 敏感阈度
                app.set_scanning_sensitivity.Value = SetDataInfo.set_scanning_sensitivity;
    
                % 扫描密度
                app.set_scanning_density.Value = SetDataInfo.set_scanning_density;
      
            %获取数据，mode = 2
            else
                scn_channel = app.scn_channel.Value;
                scn_channel_max = app.scn_channel_max.Value;
                scn_suspected_threshold = app.scn_suspected_threshold.Value;
                scn_serious_threshold = app.scn_serious_threshold.Value;
                set_scanning_sensitivity = app.set_scanning_sensitivity.Value;
                set_scanning_density = app.set_scanning_density.Value; 
                           
                
                SetDataInfo = struct('scn_channel',scn_channel,'scn_channel_max',scn_channel_max,...
                'scn_suspected_threshold',scn_suspected_threshold,'scn_serious_threshold',scn_serious_threshold,...
                'set_scanning_sensitivity',set_scanning_sensitivity,...
                'set_scanning_density',set_scanning_density);

                % 将设置好的参数保存到配置文件：SetData.csv
                SetDataInfo_temp = struct('scn_channel',str2num(scn_channel),'scn_channel_max',str2num(scn_channel_max),...
                'scn_suspected_threshold',str2num(scn_suspected_threshold),'scn_serious_threshold',str2num(scn_serious_threshold),...
                'set_scanning_sensitivity',str2num(set_scanning_sensitivity),...
                'set_scanning_density',str2num(set_scanning_density));

                dataValues = struct2cell(SetDataInfo_temp);
                namesValues = fieldnames(SetDataInfo_temp);

                % 设置配置文件路径
                configFilePath = '.\config\SetData_temp.csv';

                % 打开文件以进行写入
                fileID = fopen(configFilePath, 'wt');

                % 检查文件是否成功打开
                if fileID == -1
                    error('无法打开文件进行写入。');
                end
                
                % 遍历每一行数据并写入CSV文件
                for row = 1:size(dataValues, 1)
                    % 取出当前行的数据
                    namesData = namesValues{row};
                    rowData = dataValues{row};
                    
                    fprintf(fileID, '%s,', namesData);
                    % 写入CSV文件的第row行，第2到(end)列
                    if length(rowData) > 1
                        fprintf(fileID, '%f,', rowData(1:end-1));
                    end
                    fprintf(fileID, '%f\n', rowData(end));
                end
                
                % 关闭文件
                fclose(fileID);
    
                app.pushbutton_Return.UserData = SetDataInfo ;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.FApp = mainapp;
            SetDataInfo = app.FApp.pushbutton_Setting.UserData;
            SetDataInfo = SetData(app,SetDataInfo);
            
        end

        % Button pushed function: pushbutton_Return
        function pushbutton_ReturnButtonPushed(app, event)
            SetData(app);
            app.FApp.pushbutton_Setting.UserData = app.pushbutton_Return.UserData;

            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '文件扫描-参数设置';
            app.Panel_2 = uipanel(app.Panel);
            app.Panel_2.Title = '参数设置';
            app.text3 = uilabel(app.Panel_2);
            app.text3.Tag = 'text3';
            app.text3.Text = '待检通道';
            app.scn_channel = uieditfield(app.Panel_2, 'text');
            app.scn_channel.Tag = 'edit_MaxLimit';
            app.scn_channel.Value = '2,3,5,6,4';
            app.scn_suspected_threshold = uieditfield(app.Panel_2, 'text');
            app.scn_suspected_threshold.Tag = 'edit_MaxLimit';
            app.scn_suspected_threshold.Value = '0.25,0.25,0.25,0.25,0.25';
            app.text3_2 = uilabel(app.Panel_2);
            app.text3_2.Tag = 'text3';
            app.text3_2.Text = '故障下界';
            app.set_scanning_sensitivity = uieditfield(app.Panel_2, 'text');
            app.set_scanning_sensitivity.Tag = 'edit_MaxLimit';
            app.set_scanning_sensitivity.Value = '0.05,0.005';
            app.text3_3 = uilabel(app.Panel_2);
            app.text3_3.Tag = 'text3';
            app.text3_3.Text = '敏感阈度';
            app.set_scanning_density = uieditfield(app.Panel_2, 'text');
            app.set_scanning_density.Tag = 'edit_MaxLimit';
            app.set_scanning_density.Value = '100';
            app.text3_4 = uilabel(app.Panel_2);
            app.text3_4.Tag = 'text3';
            app.text3_4.Text = '扫描密度';
            app.scn_channel_max = uieditfield(app.Panel_2, 'text');
            app.scn_channel_max.Tag = 'edit_MaxLimit';
            app.scn_channel_max.Value = '5,5,5,5,5';
            app.text3_5 = uilabel(app.Panel_2);
            app.text3_5.Tag = 'text3';
            app.text3_5.Text = '保护间隙';
            app.scn_serious_threshold = uieditfield(app.Panel_2, 'text');
            app.scn_serious_threshold.Tag = 'edit_MaxLimit';
            app.scn_serious_threshold.Value = '0.85,0.85,0.85,0.85,0.85';
            app.text3_6 = uilabel(app.Panel_2);
            app.text3_6.Tag = 'text3';
            app.text3_6.Text = '故障上界';
            app.pushbutton_Return = uibutton(app.Panel, 'push');
            app.pushbutton_Return.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_ReturnButtonPushed, true);
            app.pushbutton_Return.Tag = 'pushbutton_Return';
            app.pushbutton_Return.Text = '确认';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetPar(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end